classdef Locate_Sensors_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LocalizeSensorsButton  matlab.ui.control.Button
        DataFileDropDown       matlab.ui.control.DropDown
        DataFileDropDownLabel  matlab.ui.control.Label
        SensorLocatorLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        homeDir; %Home Directory, based off the open folder when the main app was started
        touchData; % touchData Object (See VariableList.txt for more information)
        plottedName;
        plottedData;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, homeDir)
            app.homeDir = homeDir;
            app.plottedName = {};
            app.plottedData = {};
        end

        % Drop down opening function: DataFileDropDown
        function DataFileDropDownOpening(app, event)
            % Display all the contents in the "Obj_Files" directory
            cd(app.homeDir);

            listings = dir("Training_Data");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.DataFileDropDown.Items = itemSet;
            app.DataFileDropDown.Value = itemSet(1);
        end

        % Value changed function: DataFileDropDown
        function DataFileDropDownValueChanged(app, event)
            value = app.DataFileDropDown.Value;

            %Check if no selection was made
            if value == ""
                return
            end

            value = "Training_Data/" + value;
            app.touchData = load(value).touchData;

            %Plot the obj file 
            plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'off');

            % Autoscale limits
            %autoScale(app, app.UIAxes.Children(1));

            % Add to plotted data set
            app.plottedName{end+1} = app.DataFileDropDown.Value;
            app.plottedData{end+1} = app.UIAxes.Children(1);
        end

        % Button pushed function: LocalizeSensorsButton
        function LocalizeSensorsButtonPushed(app, event)
            value = app.DataFileDropDown.Value;

            if value == "-"
                return
            end

            addpath '/Users/nolan/Desktop/TactileContactLocalization/Scripts/Locate_Sensors_Scripts'

            sensorLocations = nolTest(app.touchData, app.plottedData{1});

            boop = 1;

            stretch = 0.005;
            for i = 1:height(sensorLocations)

                currVert = sensorLocations(i,:);

                [x,y,z] = sphere;
                x = x*stretch;
                y = y*stretch;
                z = z*stretch;
                hold(app.UIAxes,'on')
                surf(app.UIAxes, x+currVert(1), y+currVert(2), z+currVert(3),'EdgeColor','none');
                %text(app.UIAxes, currVert(1) - 0.65*stretch, currVert(2), currVert(3) + 1.5*stretch, num2str(i));
                hold(app.UIAxes,'off')

            end

            test = 1;
   
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SensorLocatorLabel = uilabel(app.UIFigure);
            app.SensorLocatorLabel.Text = 'Sensor Locator';
            app.DataFileDropDownLabel = uilabel(app.UIFigure);
            app.DataFileDropDownLabel.Text = 'Data File';
            app.DataFileDropDown = uidropdown(app.UIFigure);
            app.DataFileDropDown.Items = {'-'};
            app.DataFileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DataFileDropDownOpening, true);
            app.DataFileDropDown.ValueChangedFcn = createCallbackFcn(app, @DataFileDropDownValueChanged, true);
            app.DataFileDropDown.Value = '-';
            app.LocalizeSensorsButton = uibutton(app.UIFigure, 'push');
            app.LocalizeSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @LocalizeSensorsButtonPushed, true);
            app.LocalizeSensorsButton.Text = 'Localize Sensors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Locate_Sensors_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end