classdef Test_Model_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SkinTouchedLamp               matlab.ui.control.Lamp
        SkinTouchedLampLabel          matlab.ui.control.Label
        TouchDetectionThresholdEditField  matlab.ui.control.NumericEditField
        TouchDetectionThresholdEditFieldLabel  matlab.ui.control.Label
        CalibrateSkinButton           matlab.ui.control.Button
        UseAvgsCheckBox               matlab.ui.control.CheckBox
        SaveModelButton               matlab.ui.control.Button
        HiddenNodesEditField          matlab.ui.control.NumericEditField
        HiddenNodesEditFieldLabel     matlab.ui.control.Label
        SaveNameEditField             matlab.ui.control.EditField
        SaveNameEditFieldLabel        matlab.ui.control.Label
        StatusConsoleLabel            matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        PointLogMapSensorNumberEditField  matlab.ui.control.NumericEditField
        PointLogMapSensorNumberEditFieldLabel  matlab.ui.control.Label
        ViewModeButtonGroup           matlab.ui.container.ButtonGroup
        TouchLocalizationButton       matlab.ui.control.ToggleButton
        PointLogMapButton             matlab.ui.control.ToggleButton
        ClearPlotButton               matlab.ui.control.Button
        TrainMLModelButton            matlab.ui.control.Button
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        TrainingDataDropDown          matlab.ui.control.DropDown
        TrainingDataDropDownLabel     matlab.ui.control.Label
        ModelTrainerLabel             matlab.ui.control.Label
        DescriptionLabel              matlab.ui.control.Label
        MachineLearningModelDropDown  matlab.ui.control.DropDown
        MachineLearningModelDropDownLabel  matlab.ui.control.Label
        ModelViewerLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        homeDir; %Home Directory
        skinObj; %Serial Connection to skin
        touchData; %The data currently being used
        MLData; %The active machine learning model
        plotTimer; %Timer that decides how frequently to update plot with the touch localization 
        scat; %Scatter object that updates frequently
        CPL; %Calibration Point Log. Update this to get better results when testing out ML Models
    end
    
    methods (Access = private)
        
        function updatePlot(app)

            %Read the skin object and through into ML Model
            flush(app.skinObj);
            temp = readSkin(app.skinObj); 

            %Check that input reads is right
            if length(temp) == app.touchData.numSensors

                %Subtract the calibration levels
                temp = temp - app.CPL.sensorStateAvg;
                if max(temp) >= app.TouchDetectionThresholdEditField.Value
                    app.SkinTouchedLamp.Color = 'green';
                    %Predict the touch location
                    predLocation = app.MLData.net(temp');
        
                    %Plot the predicted touch point
                    hold(app.UIAxes, "on");
                    axis(app.UIAxes, "equal");
                    if ~isempty(app.scat), delete(app.scat); end
                    app.scat = scatter3(app.UIAxes, predLocation(1), predLocation(2), predLocation(3), 200, 'red', 'filled');
                    hold(app.UIAxes, "off");
                else
                    if ~isempty(app.scat), delete(app.scat); end
                    app.SkinTouchedLamp.Color = 'red';
                end
            end
        end

        %Log data into the touchData structure
        function calibrateSkin(app)
            %Update the status log
            app.StatusConsoleLabel.FontColor = 'k';
            app.StatusConsoleLabel.Text = "Calibrating Skin...";

            %Check that the skin sensor is connected
            if isempty(app.skinObj)
                return
            end

            %Clear the buffer
            flush(app.skinObj);

            %Take a dummy read to clear buffer
            temp = readSkin(app.skinObj);

            %Sample
            for i = 1:50
                temp = readSkin(app.skinObj);

                %Filter out incomplete reads
                repeat_count = 0;
                while length(temp) ~= app.touchData.numSensors
                    %wait
                    repeat_count = repeat_count + 1;
                    temp = readSkin(app.skinObj);

                    if repeat_count > 5
                        return
                    end
                end

                app.CPL.sensorStateRaw(i,:) = temp;
            end
                
            %Average Data
            app.CPL.sensorStateAvg = mean(app.touchData.CPL.sensorStateRaw, 1);

            %Update the status log
            app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            app.StatusConsoleLabel.Text = "Calibration Complete!";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, skinObj, homeDir)
            app.homeDir = homeDir;
            app.skinObj = skinObj;

            % Create a timer object
            app.plotTimer = timer;
            
            % Set the timer execution interval (in seconds)
            app.plotTimer.Period = 0.2; % Update every 0.5 seconds

            % Set execution mode
            app.plotTimer.ExecutionMode = 'fixedRate';

            % Define the timer callback function
            app.plotTimer.TimerFcn = @(~, ~) updatePlot(app);
        end

        % Drop down opening function: TrainingDataDropDown
        function TrainingDataDropDownOpening(app, event)
            % Display all the contents in the "Training_Data" directory
            cd(app.homeDir);

            listings = dir("Training_Data");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.TrainingDataDropDown.Items = itemSet;
            app.TrainingDataDropDown.Value = itemSet(1);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            %Clear the plot
            cla(app.UIAxes);
        end

        % Value changed function: TrainingDataDropDown
        function TrainingDataDropDownValueChanged(app, event)
            value = app.TrainingDataDropDown.Value;

            %Check if no selection was made
            if value == ""
                app.TrainMLModelButton.Enable = "off";
                return
            end
            app.TrainMLModelButton.Enable = "on";

            value = "Training_Data/" + value;
            
           %Load the touch data 
           cd(app.homeDir);
           app.touchData = load(value).touchData;
    
           %Plot a PL Map
           PlotButtonPushed(app, event);


        end

        % Selection changed function: ViewModeButtonGroup
        function ViewModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewModeButtonGroup.SelectedObject;
            ClearPlotButtonPushed(app, event);
            
            %Selected view is touch localization
            if app.TouchLocalizationButton.Value
                app.PlotButton.Enable = "off";
                app.PointLogMapSensorNumberEditField.Enable = "off";

                %Plot the skin object
                plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);

                %Calibrate skin
                if isempty(app.CPL)
                    calibrateSkin(app);
                end

                %Start updating the graph
                start(app.plotTimer);
            else
                app.PlotButton.Enable = "on";
                app.PointLogMapSensorNumberEditField.Enable = "on";

                %Stop updating the graph
                stop(app.plotTimer);

                %Plot the point log map
                PlotButtonPushed(app, event);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           %Plot a visual of a point log map
           ClearPlotButtonPushed(app, event);
           plotPLMap(app.UIAxes, app.touchData, app.PointLogMapSensorNumberEditField.Value);
           view(app.UIAxes, [30, 150]);
        end

        % Button pushed function: TrainMLModelButton
        function TrainMLModelButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%%% Training the Model %%%%%%%%%%%%%%%%%%%%%%%%%%
            %Update the status console
            app.StatusConsoleLabel.Text = "Training " + app.NameEditField.Value;

            if app.UseAvgsCheckBox.Value
                %Defining the inputs as the avg sensor data for each touch
                inputs = app.touchData.LIN.sensorStateAvg';

                %Defining the outputs as the touch locations
                outputs = app.touchData.LIN.touchPos';
            else
                %Defining the inputs as the raw sensor data for each touch
                inputs = app.touchData.LIN.sensorStateRaw';

                %Defining the outputs as the touch locations
                outputs = app.touchData.LIN.touchPosRaw';
            end
        
            % Create a feedforward neural network with input neurons equal to the number of sensors and 3 output neurons
            hiddennodes = app.HiddenNodesEditField.Value;
            app.MLData.net = feedforwardnet(hiddennodes);
            app.MLData.hiddennodes = hiddennodes;
            app.MLData.touchData = app.touchData;
            app.MLData.PLSize = length(app.touchData.PL);
            
            % Train the neural network using the inputs and targets
            %[net,tr] = train(net,dataAvgs,location);
            [app.MLData.net,tr] = train(app.MLData.net,inputs,outputs);
            app.MLData.tr = tr;

            %Update the status console
            app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            app.StatusConsoleLabel.Text = "Training Complete";
            app.SaveModelButton.Enable = "on";
            saveName = "ML_" + length(app.touchData.PL) + "PL_" + hiddennodes + "HL_" + tr.best_vperf + "MSE.mat";
            app.SaveNameEditField.Value = saveName;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%% Saving the Model %%%%%%%%%%%%%%%%%%%%%%%%%%
            cd(app.homeDir);
            app.MLData.name = app.NameEditField.Value;
            MLData = app.MLData;
            saveLoc = "ML_Models/" + app.SaveNameEditField.Value;
            save(saveLoc, "MLData");
            app.StatusConsoleLabel.Text = "Saved " + saveLoc;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Drop down opening function: MachineLearningModelDropDown
        function MachineLearningModelDropDownOpening(app, event)
                        % Display all the contents in the "Training_Data" directory
            cd(app.homeDir);

            listings = dir("ML_Models");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.MachineLearningModelDropDown.Items = itemSet;
            app.MachineLearningModelDropDown.Value = itemSet(1);
        end

        % Value changed function: MachineLearningModelDropDown
        function MachineLearningModelDropDownValueChanged(app, event)
            value = app.MachineLearningModelDropDown.Value;
            
            %Load in the model
            app.MLData = load("ML_Models/" + value).MLData;
            app.touchData = app.MLData.touchData;

            %Update the description
            app.DescriptionLabel.Text = app.MLData.name + ", " + app.MLData.PLSize + " Point Logs, " + app.MLData.hiddennodes + " Hidden Nodes, " + app.MLData.tr.best_vperf + " Root Mean Square Error in Validation.";

            %Switch the graph to the ML Localization View
            app.PointLogMapButton.Value = 0;
            app.TouchLocalizationButton.Value = 1;
            ViewModeButtonGroupSelectionChanged(app, event);

        end

        % Button pushed function: CalibrateSkinButton
        function CalibrateSkinButtonPushed(app, event)
            calibrateSkin(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Stop updating the graph
            stop(timerfindall)
            delete(timerfindall)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ModelViewerLabel = uilabel(app.UIFigure);
            app.ModelViewerLabel.Text = 'Model Viewer';
            app.MachineLearningModelDropDownLabel = uilabel(app.UIFigure);
            app.MachineLearningModelDropDownLabel.Text = 'Machine Learning Model';
            app.MachineLearningModelDropDown = uidropdown(app.UIFigure);
            app.MachineLearningModelDropDown.Items = {'-'};
            app.MachineLearningModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MachineLearningModelDropDownOpening, true);
            app.MachineLearningModelDropDown.ValueChangedFcn = createCallbackFcn(app, @MachineLearningModelDropDownValueChanged, true);
            app.MachineLearningModelDropDown.Value = '-';
            app.DescriptionLabel = uilabel(app.UIFigure);
            app.DescriptionLabel.Text = 'Description';
            app.ModelTrainerLabel = uilabel(app.UIFigure);
            app.ModelTrainerLabel.Text = 'Model Trainer';
            app.TrainingDataDropDownLabel = uilabel(app.UIFigure);
            app.TrainingDataDropDownLabel.Text = 'Training Data';
            app.TrainingDataDropDown = uidropdown(app.UIFigure);
            app.TrainingDataDropDown.Items = {'-'};
            app.TrainingDataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrainingDataDropDownOpening, true);
            app.TrainingDataDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingDataDropDownValueChanged, true);
            app.TrainingDataDropDown.Value = '-';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Value = 'Touch Localizer for Cone';
            app.TrainMLModelButton = uibutton(app.UIFigure, 'push');
            app.TrainMLModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMLModelButtonPushed, true);
            app.TrainMLModelButton.Enable = 'off';
            app.TrainMLModelButton.Text = 'Train ML Model';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ViewModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewModeButtonGroupSelectionChanged, true);
            app.ViewModeButtonGroup.Title = 'View Mode';
            app.PointLogMapButton = uitogglebutton(app.ViewModeButtonGroup);
            app.PointLogMapButton.Text = 'Point Log Map';
            app.PointLogMapButton.Value = true;
            app.TouchLocalizationButton = uitogglebutton(app.ViewModeButtonGroup);
            app.TouchLocalizationButton.Text = 'Touch Localization';
            app.PointLogMapSensorNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PointLogMapSensorNumberEditFieldLabel.Text = 'Point Log Map Sensor Number';
            app.PointLogMapSensorNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointLogMapSensorNumberEditField.Value = 1;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StatusConsoleLabel = uilabel(app.UIFigure);
            app.StatusConsoleLabel.Text = 'StatusConsole';
            app.SaveNameEditFieldLabel = uilabel(app.UIFigure);
            app.SaveNameEditFieldLabel.Enable = 'off';
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveNameEditField.Enable = 'off';
            app.SaveNameEditField.Value = '-';
            app.HiddenNodesEditFieldLabel = uilabel(app.UIFigure);
            app.HiddenNodesEditFieldLabel.Text = 'Hidden Nodes';
            app.HiddenNodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.HiddenNodesEditField.Value = 10;
            app.SaveModelButton = uibutton(app.UIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Enable = 'off';
            app.SaveModelButton.Text = 'Save Model';
            app.UseAvgsCheckBox = uicheckbox(app.UIFigure);
            app.UseAvgsCheckBox.Text = 'Use Avgs';
            app.CalibrateSkinButton = uibutton(app.UIFigure, 'push');
            app.CalibrateSkinButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateSkinButtonPushed, true);
            app.CalibrateSkinButton.Text = 'Calibrate Skin';
            app.TouchDetectionThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.TouchDetectionThresholdEditFieldLabel.Text = 'Touch Detection Threshold';
            app.TouchDetectionThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.TouchDetectionThresholdEditField.Value = 50;
            app.SkinTouchedLampLabel = uilabel(app.UIFigure);
            app.SkinTouchedLampLabel.Text = 'Skin Touched';
            app.SkinTouchedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test_Model_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end