classdef Validate_Model_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        CalibrationSamplesEditField  matlab.ui.control.NumericEditField
        CalibrationSamplesEditFieldLabel  matlab.ui.control.Label
        SampleSizeEditField          matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel     matlab.ui.control.Label
        ClearPlotButton_2            matlab.ui.control.Button
        MLFileDropDown               matlab.ui.control.DropDown
        MLFileDropDownLabel          matlab.ui.control.Label
        TitleEditField               matlab.ui.control.EditField
        TitleEditFieldLabel          matlab.ui.control.Label
        ResetValidationButton        matlab.ui.control.Button
        ConfirmValidationButton      matlab.ui.control.Button
        ClearPlotButton              matlab.ui.control.Button
        ValidateModelLabel           matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        PointLogCountEditField       matlab.ui.control.NumericEditField
        PointLogCountEditFieldLabel  matlab.ui.control.Label
        StatusConsoleLabel           matlab.ui.control.Label
        DescriptionEditField         matlab.ui.control.EditField
        DescriptionEditFieldLabel    matlab.ui.control.Label
        SaveLocationEditField        matlab.ui.control.EditField
        SaveLocationEditFieldLabel   matlab.ui.control.Label
        StartValidationButton        matlab.ui.control.Button
        PointLogsGauge               matlab.ui.control.NinetyDegreeGauge
        PointLogsGaugeLabel          matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        skinObj; %Skin sensor serial connection
        homeDir; %Home Directory, based off the open folder when the main app was started
        touchData; % touchData Object (See VariableList.txt for more information)
        plottedName;
        plottedData;
        collect; %Bool 
        MLData; %The active machine learning model
        plotTimer; %Timer that decides how frequently to update plot with the touch localization 
        scat; %Scatter object that updates frequently
        CPL; %Calibration Point Log. Update this to get better results when testing out ML Models
    end
    
    methods (Access = private)

        function updatePlot(app)

            %Read the skin object and through into ML Model
            flush(app.skinObj);
            temp = readSkin(app.skinObj); 

            %Check that input reads is right
            if length(temp) == app.touchData.numSensors

                %Subtract the calibration levels
                temp = temp - app.CPL.sensorStateAvg;
                if max(temp) >= app.TouchDetectionThresholdEditField.Value
                    app.SkinTouchedLamp.Color = 'green';
                    %Predict the touch location
                    predLocation = app.MLData.net(temp');
        
                    %Plot the predicted touch point
                    hold(app.UIAxes, "on");
                    axis(app.UIAxes, "equal");
                    if ~isempty(app.scat), delete(app.scat); end
                    app.scat = scatter3(app.UIAxes, predLocation(1), predLocation(2), predLocation(3), 200, 'red', 'filled');
                    hold(app.UIAxes, "off");
                else
                    if ~isempty(app.scat), delete(app.scat); end
                    app.SkinTouchedLamp.Color = 'red';
                end
            end
        end


        %Log data into the touchData structure
        function calibrateSkin(app)

            %Check that the skin sensor is connected
            if isempty(app.skinObj)
                return
            end

            %Clear the buffer
            flush(app.skinObj);

            %Take a dummy read to clear buffer
            temp = readSkin(app.skinObj);

            %Sample
            for i = 1:app.SampleSizeEditField.Value
                temp = readSkin(app.skinObj);

                %Filter out incomplete reads
                repeat_count = 0;
                while length(temp) ~= app.touchData.numSensors
                    %wait
                    repeat_count = repeat_count + 1;
                    temp = readSkin(app.skinObj);

                    if repeat_count > 5
                        return
                    end
                end

                app.touchData.CPL.sensorStateRaw(i,:) = temp;
            end
                
            %Average Data
            app.touchData.CPL.sensorStateAvg = mean(app.touchData.CPL.sensorStateRaw, 1);
        end
        
        %Log data into the touchData structure
        function logData(app)

            %Check that the skin sensor is connected
            if isempty(app.skinObj)
                return
            end

            %Clear the buffer
            flush(app.skinObj);

            %Take a dummy read to clear buffer
            temp = readSkin(app.skinObj);

            %Sample
            for i = 1:app.SampleSizeEditField.Value
                temp = readSkin(app.skinObj);
                app.StatusConsoleLabel.Text = "Data Read Size of :" + string(length(temp));

                %Filter out incomplete reads
                repeat_count = 0;
                while length(temp) ~= app.touchData.numSensors
                    %wait
                    repeat_count = repeat_count + 1;
                    temp = readSkin(app.skinObj);

                    if repeat_count > 5
                        return
                    end
                end

                app.touchData.PL(app.PointLogsGauge.Value).sensorStateRaw(i,:) = temp - app.touchData.CPL.sensorStateAvg;
            end

            %Average Data
            app.touchData.PL(app.PointLogsGauge.Value).sensorStateAvg = mean(app.touchData.PL(app.PointLogsGauge.Value).sensorStateRaw, 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, skinObj, homeDir)
            app.skinObj = skinObj;
            app.homeDir = homeDir;
            app.plottedName = {};
            app.plottedData = {};

            % Create a timer object
            app.plotTimer = timer;
            
            % Set the timer execution interval (in seconds)
            app.plotTimer.Period = 0.2; % Update every 0.5 seconds

            % Set execution mode
            app.plotTimer.ExecutionMode = 'fixedRate';

            % Define the timer callback function
            app.plotTimer.TimerFcn = @(~, ~) updatePlot(app);
        end

        % Callback function: not associated with a component
        function ObjectFileDropDownOpening(app, event)
            % Display all the contents in the "Obj_Files" directory
            cd(app.homeDir);

            listings = dir("Obj_Files");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.ObjectFileDropDown.Items = itemSet;
            app.ObjectFileDropDown.Value = itemSet(1);
        end

        % Callback function: not associated with a component
        function ObjectFileDropDownValueChanged(app, event)
            value = app.ObjectFileDropDown.Value;

            %Check if no selection was made
            if value == ""
                return
            end

            value = "Obj_Files/" + value;
            
            %Save the vertices and facets of the selected object and plot
            %them
            try
                [app.touchData.obj.f, app.touchData.obj.v] = read_obj(value);
            catch
                out = readObj(value);
                app.touchData.obj.f = out.f.v;
                app.touchData.obj.v = out.v;
            end

            %Save name
            app.touchData.obj.fileLoc = value;

            %Plot the obj file 
            plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'off');

            % Autoscale limits
            autoScale(app, app.UIAxes.Children(1));

            % Add to plotted data set
            % app.plottedName{end+1} = app.ObjectFileDropDown.Value;
            app.plottedData{end+1} = app.UIAxes.Children(1);

        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            app.MLFileDropDown.Value = app.MLFileDropDown.Items(1);
            app.plottedName = {};
            app.plottedData = {};
        end

        % Button pushed function: StartValidationButton
        function StartValidationButtonPushed(app, event)

            % app.collect = true;
            % pause on;
            % 
            % %Initialize Data Struct - Keep unkowns empty
            % app.touchData.numTX = app.TXCountEditField.Value;
            % app.touchData.numRX = app.RXCountEditField.Value;
            % app.touchData.numSensors = app.TXCountEditField.Value * app.RXCountEditField.Value;
            % 
            % %Set the limit of the gauge
            % app.PointLogsGauge.Limits = [0, app.PointLogCountEditField.Value];
            % app.PointLogsGauge.MajorTicks = [0, app.PointLogCountEditField.Value/2, app.PointLogCountEditField.Value];
            % %app.PointLogsGauge.MajorTicksLabels = [0, app.PointLogCountEditField.Value/2, app.PointLogCountEditField.Value];
            % 
            % 
            % % Start by getting the stats of the plotted shape
            % % Assume only one plot active
            % if ~isempty(app.UIAxes.Children)
            %     [indx,~] = listdlg('PromptString',{'Select Object to Sample On'}, 'SelectionMode','single','ListString',app.plottedName,'ListSize',[200,100]);
            %     if ~isempty(indx)
            %         data = app.plottedData{indx};
            %         autoScale(app, data);
            %         data.FaceColor = "green";
            %         xBound = [min(data.XData,[],'All'), max(data.XData,[],'All')];
            %         yBound = [min(data.YData,[],'All'), max(data.YData,[],'All')];
            %         zBound = [min(data.ZData,[],'All'), max(data.ZData,[],'All')];
            %         xWidth = xBound(2) - xBound(1);
            %         yWidth = yBound(2) - yBound(1);
            %         zWidth = zBound(2) - zBound(1);
            %         avgWidth = mean([xWidth, yWidth, zWidth]);
            %         numSamples = app.PointLogCountEditField.Value;
            %         % stretch = 5/(2*numSamples);
            %         stretch = 1/20;
            %         i = 1; %PL number
            %         app.PointLogsGauge.Value = i;
            % 
            %         %%%%%%%%%% Calibration of sensor %%%%%%%%%%%%%%%%%%%
            %         app.StatusConsoleLabel.Text = "Calibrating Sensor...";
            %         calibrateSkin(app);
            %         app.StatusConsoleLabel.Text = "Press Spacebar in Console to Take Data";
            %         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 
            %         %Data collection loop
            %         while app.collect == true && i <= numSamples
            %             % Generate a random vertice on the object 
            %             currVert = getRandomSample(data);
            %             %currVert = data.Vertices(randi([1, height(data.Vertices)]),:);
            %             [x,y,z] = sphere;
            %             x = x*avgWidth*stretch;
            %             y = y*avgWidth*stretch;
            %             z = z*avgWidth*stretch;
            %             hold(app.UIAxes,'on')
            %             surf(app.UIAxes, x+currVert(1), y+currVert(2), z+currVert(3),'FaceColor','red','EdgeColor','none');
            %             hold(app.UIAxes,'off')
            %             % Change view so that it clearly shows where the
            %             % touch point is
            %             if currVert(1) < 0
            %                 view(app.UIAxes,[30, 150])
            %             else
            %                 view(app.UIAxes,[-30, 150])
            %             end
            %             % view(app.UIAxes,currVert)
            %             pause; % pauses until a button is pressed in the command window
            % 
            %             %%%%%%%%%%%%%% Collecting Data %%%%%%%%%%%%%%
            %             % After pressing a button in the command window,
            %             % clear the serial buffer, sample as many times as
            %             % specified in the app, then save to touch data
            % 
            %             logData(app);
            %             app.touchData.PL(app.PointLogsGauge.Value).touchPos = currVert;
            % 
            %             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 
            %             %Iterate point log gauge - Used to specify which
            %             %touch is being logged in logData()
            %             if app.collect == true
            %                 i = i+1;
            %                 app.PointLogsGauge.Value = i;
            %             end
            % 
            %             %Deletes the scattered point
            %             delete(app.UIAxes.Children(1)); 
            %         end
            % 
            %         app.collect = false;
            %         app.StatusConsoleLabel.Text = "Competed Data Collection";
            %         app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            %         app.ConfirmValidationButton.Enable = "on";
            %         % app.StartValidationButton.Text = "Reset Collection";
            %     end
            % end


            % Prompt user to touch a certain area of the skin

            app.collect = true;
            pause on;

            % Start by getting the stats of the plotted shape
            % Assume only one plot active
            if ~isempty(app.UIAxes.Children)
                data = app.plottedData{1};
                autoScale(app, data);
                data.FaceColor = "green";
                xBound = [min(data.XData,[],'All'), max(data.XData,[],'All')];
                yBound = [min(data.YData,[],'All'), max(data.YData,[],'All')];
                zBound = [min(data.ZData,[],'All'), max(data.ZData,[],'All')];
                xWidth = xBound(2) - xBound(1);
                yWidth = yBound(2) - yBound(1);
                zWidth = zBound(2) - zBound(1);
                avgWidth = mean([xWidth, yWidth, zWidth]);
                numSamples = app.PointLogCountEditField.Value;
                % stretch = 5/(2*numSamples);
                stretch = 1/20;
                i = 1; %PL number
                app.PointLogsGauge.Value = i;

                %%%%%%%%%% Calibration of sensor %%%%%%%%%%%%%%%%%%%
                app.StatusConsoleLabel.Text = "Calibrating Sensor...";
                calibrateSkin(app);
                app.StatusConsoleLabel.Text = "Press Spacebar in Console to Take Data";
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Data collection loop
                while app.collect == true && i <= numSamples
                    % Generate a random vertice on the object 
                    currVert = getRandomSample(data);
                    %currVert = data.Vertices(randi([1, height(data.Vertices)]),:);
                    [x,y,z] = sphere;
                    x = x*avgWidth*stretch;
                    y = y*avgWidth*stretch;
                    z = z*avgWidth*stretch;
                    hold(app.UIAxes,'on')
                    surf(app.UIAxes, x+currVert(1), y+currVert(2), z+currVert(3),'FaceColor','red','EdgeColor','none');
                    hold(app.UIAxes,'off')
                    % Change view so that it clearly shows where the
                    % touch point is
                    if currVert(1) < 0
                        view(app.UIAxes,[30, 150])
                    else
                        view(app.UIAxes,[-30, 150])
                    end
                    % view(app.UIAxes,currVert)
                    pause; % pauses until a button is pressed in the command window

                    %%%%%%%%%%%%%% Collecting Data %%%%%%%%%%%%%%
                    % After pressing a button in the command window,
                    % clear the serial buffer, sample as many times as
                    % specified in the app, then save to touch data

                    logData(app);
                    app.touchData.PL(app.PointLogsGauge.Value).touchPos = currVert;

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    %%%%%%%%%%%%%% Logging Prediction %%%%%%%%%%%%%%


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    %Iterate point log gauge - Used to specify which
                    %touch is being logged in logData()
                    if app.collect == true
                        i = i+1;
                        app.PointLogsGauge.Value = i;
                    end


                    %Deletes the scattered point
                    delete(app.UIAxes.Children(1)); 
                end

                app.collect = false;
                app.StatusConsoleLabel.Text = "Competed Data Collection";
                app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
                app.ConfirmValidationButton.Enable = "on";
                % app.StartValidationButton.Text = "Reset Collection";
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Confirm Close');
            if strcmpi(YN,'OK')
                app.plottedName = {};
                app.plottedData = {};
                delete(app)
            end
        end

        % Button pushed function: ConfirmValidationButton
        function ConfirmValidationButtonPushed(app, event)
            % Break loop
            app.collect = false;
               
            % Collect/write data from home dir
            cd(app.homeDir);

            %Format the data to save single matrices of touch position and
            %sensor state average for convenience
            app.touchData.LIN.sensorStateAvg = zeros(length(app.touchData.PL), app.touchData.numSensors);
            app.touchData.LIN.touchPos = zeros(length(app.touchData.PL), 3);
            raw_counter = 1;
            for i = 1:length(app.touchData.PL)
                %Avg data
                app.touchData.LIN.sensorStateAvg(i,:) = app.touchData.PL(i).sensorStateAvg;
                app.touchData.LIN.touchPos(i,:) = app.touchData.PL(i).touchPos;


                %Raw data
                for j = 1:size(app.touchData.PL(i).sensorStateRaw, 1)
                    app.touchData.LIN.sensorStateRaw(raw_counter, :) = app.touchData.PL(i).sensorStateRaw(j, :);
                    app.touchData.LIN.touchPosRaw(raw_counter,:) = app.touchData.PL(i).touchPos;
                    raw_counter = raw_counter+1;
                end
            end

            %%%%%% Save Location %%%%%%%%
            fileName = app.TitleEditField.Value + "_RND_" + length(app.touchData.PL) + "PL_"; 
            fileType = ".mat";
            count = 1;
            pathName = fileName + count + fileType;

            %fileContents = dir(app.SaveLocationEditField.Value);
            %Check to see if the file name already exists, if it does,
            %increment by 1
            while exist(app.SaveLocationEditField.Value + pathName, "file")
                count = count + 1;
                pathName = fileName+count+fileType;
            end
            app.StatusConsoleLabel.Text = app.SaveLocationEditField.Value + pathName;
            try
                touchData = app.touchData;
                save(app.SaveLocationEditField.Value + pathName, 'touchData');
                app.StatusConsoleLabel.Text = "Save Complete! -> " + app.StatusConsoleLabel.Text;
                app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            catch
                app.StatusConsoleLabel.Text = "Save Failed! -> " + app.StatusConsoleLabel.Text;
                app.StatusConsoleLabel.FontColor = [1,0,0];
            end

            %disp("Wrote Collected Data")
        end

        % Button pushed function: ResetValidationButton
        function ResetValidationButtonPushed(app, event)
            % Break loop
            app.collect = false;
            app.PointLogsGauge.Value = 1;
            
            % Reset data...
            disp("Reset Collection of Data")
        end

        % Value changed function: MLFileDropDown
        function MLFileDropDownValueChanged(app, event)
            value = app.MLFileDropDown.Value;
        
            %Load in the model
            app.MLData = load("ML_Models/" + value).MLData;

            app.touchData = app.MLData.touchData;

            %Plot the obj file 
            plotOBJ(app.UIAxes, app.MLData.touchData.obj.v, app.MLData.touchData.obj.f);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'off');

            % Autoscale limits
            autoScale(app, app.UIAxes.Children(1));

            % Add to plotted data set
            % app.plottedName{end+1} = app.ObjectFileDropDown.Value;
            app.plottedData{end+1} = app.UIAxes.Children(1);
        end

        % Drop down opening function: MLFileDropDown
        function MLFileDropDownOpening(app, event)
            % Display all the contents in the "Training_Data" directory
            cd(app.homeDir);

            listings = dir("ML_Models");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end

            app.MLFileDropDown.Items = itemSet;
            app.MLFileDropDown.Value = itemSet(1);

        end

        % Value changed function: PointLogCountEditField
        function PointLogCountEditFieldValueChanged(app, event)
            value = app.PointLogCountEditField.Value;
            
            app.PointLogsGauge.Limits = [0, value];
            app.PointLogsGauge.MajorTicks = 0:25:(value - mod(value,25));
            app.PointLogsGauge.MinorTicks = 0:10:(value - mod(value,10));
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PointLogsGaugeLabel = uilabel(app.UIFigure);
            app.PointLogsGaugeLabel.Text = 'Point Logs';
            app.PointLogsGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.PointLogsGauge.Limits = [0 50];
            app.PointLogsGauge.MajorTicks = [0 25 50];
            app.PointLogsGauge.Value = 1;
            app.StartValidationButton = uibutton(app.UIFigure, 'push');
            app.StartValidationButton.ButtonPushedFcn = createCallbackFcn(app, @StartValidationButtonPushed, true);
            app.StartValidationButton.Text = 'Start Validation';
            app.SaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SaveLocationEditField.Value = 'Validation_Data\';
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Value = '3D ML Sensor 1';
            app.StatusConsoleLabel = uilabel(app.UIFigure);
            app.StatusConsoleLabel.Text = 'StatusConsole';
            app.PointLogCountEditFieldLabel = uilabel(app.UIFigure);
            app.PointLogCountEditFieldLabel.Text = 'Point Log Count';
            app.PointLogCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointLogCountEditField.ValueChangedFcn = createCallbackFcn(app, @PointLogCountEditFieldValueChanged, true);
            app.PointLogCountEditField.Value = 20;
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Random Sampling', 'Even Spacing'};
            app.MethodDropDown.Value = 'Random Sampling';
            app.ValidateModelLabel = uilabel(app.UIFigure);
            app.ValidateModelLabel.Text = 'Validate Model';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ConfirmValidationButton = uibutton(app.UIFigure, 'push');
            app.ConfirmValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmValidationButtonPushed, true);
            app.ConfirmValidationButton.Enable = 'off';
            app.ConfirmValidationButton.Text = 'Confirm Validation';
            app.ResetValidationButton = uibutton(app.UIFigure, 'push');
            app.ResetValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValidationButtonPushed, true);
            app.ResetValidationButton.Text = 'Reset Validation';
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.Value = 'ConeSkinData';
            app.MLFileDropDownLabel = uilabel(app.UIFigure);
            app.MLFileDropDownLabel.Text = 'ML File';
            app.MLFileDropDown = uidropdown(app.UIFigure);
            app.MLFileDropDown.Items = {'-'};
            app.MLFileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MLFileDropDownOpening, true);
            app.MLFileDropDown.ValueChangedFcn = createCallbackFcn(app, @MLFileDropDownValueChanged, true);
            app.MLFileDropDown.Value = '-';
            app.ClearPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.SampleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleSizeEditField.Value = 50;
            app.CalibrationSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationSamplesEditFieldLabel.Text = 'Calibration Samples';
            app.CalibrationSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationSamplesEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Validate_Model_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end