classdef Validate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AverageSNRLabel              matlab.ui.control.Label
        SaveLocationEditField        matlab.ui.control.EditField
        SaveLocationEditFieldLabel   matlab.ui.control.Label
        SaveNameEditFieldLabel       matlab.ui.control.Label
        SaveNameEditField            matlab.ui.control.EditField
        StatusConsoleLabel           matlab.ui.control.Label
        ResetValidationButton        matlab.ui.control.Button
        ConfirmValidationButton      matlab.ui.control.Button
        PointLogsGauge               matlab.ui.control.NinetyDegreeGauge
        PointLogsGaugeLabel          matlab.ui.control.Label
        PointLogCountEditField       matlab.ui.control.NumericEditField
        PointLogCountEditFieldLabel  matlab.ui.control.Label
        StartValidationButton        matlab.ui.control.Button
        SkinTouchedLamp              matlab.ui.control.Lamp
        SkinTouchedLampLabel         matlab.ui.control.Label
        TouchDetectionThresholdEditField  matlab.ui.control.NumericEditField
        TouchDetectionThresholdEditFieldLabel  matlab.ui.control.Label
        CalibrateSkinButton          matlab.ui.control.Button
        ClearPlotButton              matlab.ui.control.Button
        DescriptionLabel             matlab.ui.control.Label
        SelectMLModelDropDown        matlab.ui.control.DropDown
        SelectMLModelDropDownLabel   matlab.ui.control.Label
        ValidateModelLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        homeDir; %Home Directory
        skinObj; %Serial Connection to skin
        touchData; %The data currently being used
        MLData; %The active machine learning model
        validateData; % The validation data to save
        plotTimer; %Timer that decides how frequently to update plot with the touch localization 
        scat; %Scatter object that updates frequently
        promptPoint; % Scatter object for prompted point
        CPL; %Calibration Point Log. Update this to get better results when testing out ML Models
        plottedName;
        plottedData;
        collect; %Bool 
    end
    
    methods (Access = private)
        
        function updatePlot(app)

            %Read the skin object and through into ML Model
            flush(app.skinObj);
            temp = readSkin(app.skinObj); 

            %Check that input reads is right
            if length(temp) == app.touchData.numSensors

                %Subtract the calibration levels
                temp = temp - app.CPL.sensorStateAvg;
                if max(temp) >= app.TouchDetectionThresholdEditField.Value
                    app.SkinTouchedLamp.Color = 'green';
                    %Predict the touch location
                    predLocationRaw = app.MLData.net(temp')';

                    % NOW CONTRAIN PREDICTED LOCATION TO SURFACE
                    predLocation = constrainOutput(predLocationRaw, app.MLData.touchData.obj.Points);
        
                    %Plot the predicted touch point
                    hold(app.UIAxes, "on");
                    axis(app.UIAxes, "equal");
                    if ~isempty(app.scat), delete(app.scat); end
                    app.scat = scatter3(app.UIAxes, predLocation(1), predLocation(2), predLocation(3), 200, 'red', 'filled');
                    hold(app.UIAxes, "off");
                else
                    if ~isempty(app.scat), delete(app.scat); end
                    app.SkinTouchedLamp.Color = 'red';
                end
            end
        end

        %Log data into the touchData structure
        function calibrateSkin(app)
            %Update the status log
            app.StatusConsoleLabel.FontColor = 'k';
            app.StatusConsoleLabel.Text = "Calibrating Skin...";

            %Check that the skin sensor is connected
            if isempty(app.skinObj)
                return
            end

            %Clear the buffer
            flush(app.skinObj);

            %Take a dummy read to clear buffer
            temp = readSkin(app.skinObj);

            %Sample
            for i = 1:50
                temp = readSkin(app.skinObj);

                %Filter out incomplete reads
                repeat_count = 0;
                while length(temp) ~= app.touchData.numSensors
                    %wait
                    repeat_count = repeat_count + 1;
                    temp = readSkin(app.skinObj);

                    if repeat_count > 5
                        return
                    end
                end

                app.CPL.sensorStateRaw(i,:) = temp;
            end
                
            %Average Data
            app.CPL.sensorStateAvg = mean(app.touchData.CPL.sensorStateRaw, 1);

            %Update the status log
            app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            app.StatusConsoleLabel.Text = "Calibration Complete!";
        end

        %Log data into the touchData structure
        function logData(app)

            %Check that the skin sensor is connected
            if isempty(app.skinObj)
                return
            end

            if isempty(app.scat)
                return; % if no data collected at that point
            end 

            % Otherwise, store data in app.validateData
            prompt = [app.promptPoint.XData, app.promptPoint.YData, app.promptPoint.ZData];
            reading = [app.scat.XData, app.scat.YData, app.scat.ZData];
       
            app.validateData.prompts(end+1,:) = prompt;
            app.validateData.readings(end+1,:) = reading;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, skinObj, homeDir)
            app.homeDir = homeDir;
            app.skinObj = skinObj;

            app.validateData.prompts = [];
            app.validateData.readings = [];

            % Create a timer object
            app.plotTimer = timer;
            
            % Set the timer execution interval (in seconds)
            app.plotTimer.Period = 0.2; % Update every 0.5 seconds

            % Set execution mode
            app.plotTimer.ExecutionMode = 'fixedRate';

            % Define the timer callback function
            app.plotTimer.TimerFcn = @(~, ~) updatePlot(app);
        end

        % Callback function
        function TrainingDataDropDownOpening(app, event)
            % Display all the contents in the "Training_Data" directory
            cd(app.homeDir);

            listings = dir("Training_Data");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.TrainingDataDropDown.Items = itemSet;
            app.TrainingDataDropDown.Value = itemSet(1);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            %Clear the plot
            cla(app.UIAxes);
        end

        % Callback function
        function TrainingDataDropDownValueChanged(app, event)
            value = app.TrainingDataDropDown.Value;

            %Check if no selection was made
            if value == ""
                app.TrainMLModelButton.Enable = "off";
                return
            end
            app.TrainMLModelButton.Enable = "on";

            value = "Training_Data/" + value;
            
           %Load the touch data 
           cd(app.homeDir);
           app.touchData = load(value).touchData;

           %Calculate the signal to noise ratio
           app.AverageSNRLabel.Text = "Average SNR: " + string(evalSNR(app.touchData));
    
           %Plot a PL Map
           PlotButtonPushed(app, event);


        end

        % Callback function
        function ViewModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewModeButtonGroup.SelectedObject;
            ClearPlotButtonPushed(app, event);
            
            %Selected view is touch localization
            if app.TouchLocalizationButton.Value
                app.PlotButton.Enable = "off";
                app.PointLogMapSensorNumberEditField.Enable = "off";

                %Plot the skin object
                plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);

                %Calibrate skin
                if isempty(app.CPL)
                    calibrateSkin(app);
                end

                %Start updating the graph
                start(app.plotTimer);
            else
                app.PlotButton.Enable = "on";
                app.PointLogMapSensorNumberEditField.Enable = "on";

                %Stop updating the graph
                stop(app.plotTimer);

                %Plot the point log map
                PlotButtonPushed(app, event);
            end
        end

        % Callback function
        function PlotButtonPushed(app, event)
           %Plot a visual of a point log map
           ClearPlotButtonPushed(app, event);
           plotPLMap(app.UIAxes, app.touchData, app.PointLogMapSensorNumberEditField.Value);
           view(app.UIAxes, [30, 150]);
        end

        % Callback function
        function TrainMLModelButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%%% Training the Model %%%%%%%%%%%%%%%%%%%%%%%%%%
            %Update the status console
            app.StatusConsoleLabel.Text = "Training " + app.NameEditField.Value;

            if app.UseAvgsCheckBox.Value
                %Defining the inputs as the avg sensor data for each touch
                inputs = app.touchData.LIN.sensorStateAvg';

                %Defining the outputs as the touch locations
                outputs = app.touchData.LIN.touchPos';
            else
                %Defining the inputs as the raw sensor data for each touch
                inputs = app.touchData.LIN.sensorStateRaw';

                %Defining the outputs as the touch locations
                outputs = app.touchData.LIN.touchPosRaw';
            end
        
            % Create a feedforward neural network with input neurons equal to the number of sensors and 3 output neurons
            hiddennodes = app.HiddenNodesEditField.Value;
            app.MLData.net = feedforwardnet(hiddennodes);
            app.MLData.hiddennodes = hiddennodes;
            app.MLData.touchData = app.touchData;
            app.MLData.PLSize = length(app.touchData.PL);
            
            % Train the neural network using the inputs and targets
            %[net,tr] = train(net,dataAvgs,location);
            [app.MLData.net,tr] = train(app.MLData.net,inputs,outputs);
            app.MLData.tr = tr;

            %Update the status console
            app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            app.StatusConsoleLabel.Text = "Training Complete";
            app.SaveModelButton.Enable = "on";
            saveName = "ML_" + length(app.touchData.PL) + "PL_" + hiddennodes + "HL_" + tr.best_vperf + "MSE.mat";
            app.SaveNameEditField.Value = saveName;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Callback function
        function SaveModelButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%% Saving the Model %%%%%%%%%%%%%%%%%%%%%%%%%%
            cd(app.homeDir);
            app.MLData.name = app.NameEditField.Value;
            MLData = app.MLData;
            saveLoc = "ML_Models/" + app.SaveNameEditField.Value;
            save(saveLoc, "MLData");
            app.StatusConsoleLabel.Text = "Saved " + saveLoc;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Drop down opening function: SelectMLModelDropDown
        function SelectMLModelDropDownOpening(app, event)
                        % Display all the contents in the "Training_Data" directory
            cd(app.homeDir);

            listings = dir("ML_Models");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.SelectMLModelDropDown.Items = itemSet;
            app.SelectMLModelDropDown.Value = itemSet(1);
        end

        % Value changed function: SelectMLModelDropDown
        function SelectMLModelDropDownValueChanged(app, event)
            value = app.SelectMLModelDropDown.Value;

            %Load in the model
            app.MLData = load("ML_Models/" + value).MLData;
            app.touchData = app.MLData.touchData;

            %Update the description
            app.DescriptionLabel.Text = app.MLData.name + ", " + app.MLData.PLSize + " Point Logs, " + app.MLData.hiddennodes + " Hidden Nodes, " + app.MLData.tr.best_vperf + " Root Mean Square Error in Validation.";

            %Switch the graph to the ML Localization View
            % app.PointLogMapButton.Value = 0;
            % app.TouchLocalizationButton.Value = 1;
            %ViewModeButtonGroupSelectionChanged(app, event);

           
            %app.PlotButton.Enable = "off";
            %app.PointLogMapSensorNumberEditField.Enable = "off";

            %Plot the skin object
            plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'off');

            % Autoscale limits
            autoScale(app, app.UIAxes.Children(1));

            % Add to plotted data set
            app.plottedData{end+1} = app.UIAxes.Children(1);

            %Calibrate skin
            if isempty(app.CPL)
                calibrateSkin(app);
            end

            %Start updating the graph for touch inputs
            start(app.plotTimer);

        end

        % Button pushed function: CalibrateSkinButton
        function CalibrateSkinButtonPushed(app, event)
            calibrateSkin(app);
        end

        % Value changed function: PointLogCountEditField
        function PointLogCountEditFieldValueChanged(app, event)
            value = app.PointLogCountEditField.Value;
            
            app.PointLogsGauge.Limits = [0, value];
            app.PointLogsGauge.MajorTicks = 0:25:(value - mod(value,25));
            app.PointLogsGauge.MinorTicks = 0:10:(value - mod(value,10));
            
        end

        % Button pushed function: StartValidationButton
        function StartValidationButtonPushed(app, event)
              
            app.validateData.numCalibrations = app.MLData.PLSize;

            % Prompt user to touch a certain area of the skin

            app.collect = true;
            pause on;

            % Start by getting the stats of the plotted shape
            % Assume only one plot active
            if ~isempty(app.UIAxes.Children)
                data = app.plottedData{1};
                autoScale(app, data);
                data.FaceColor = "green";
                xBound = [min(data.XData,[],'All'), max(data.XData,[],'All')];
                yBound = [min(data.YData,[],'All'), max(data.YData,[],'All')];
                zBound = [min(data.ZData,[],'All'), max(data.ZData,[],'All')];
                xWidth = xBound(2) - xBound(1);
                yWidth = yBound(2) - yBound(1);
                zWidth = zBound(2) - zBound(1);
                avgWidth = mean([xWidth, yWidth, zWidth]);
                numSamples = app.PointLogCountEditField.Value;
                % stretch = 5/(2*numSamples);
                stretch = 1/20;
                i = 1; %PL number
                app.PointLogsGauge.Value = i;

                %%%%%%%%%% Calibration of sensor %%%%%%%%%%%%%%%%%%%
                app.StatusConsoleLabel.Text = "Calibrating Sensor...";
                calibrateSkin(app);
                app.StatusConsoleLabel.Text = "Press Spacebar in Console to Take Data";
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Data collection loop
                while app.collect == true && i <= numSamples
                    % Generate a random vertice on the object 
                    currVert = getRandomSample(data);
                    if ~isempty(app.promptPoint), delete(app.promptPoint); end
                    hold(app.UIAxes,'on')
                    app.promptPoint = scatter3(app.UIAxes, currVert(1), currVert(2), currVert(3), 200, 'black', 'filled');
                    hold(app.UIAxes,'off')
                    % Change view so that it clearly shows where the
                    % touch point is
                    if currVert(1) < 0
                        view(app.UIAxes,[30, 150])
                    else
                        view(app.UIAxes,[-30, 150])
                    end
                    pause; % pauses until a button is pressed in the command window

                    if app.collect == true

                        %%%%%%%%%%%%%% Collecting Data %%%%%%%%%%%%%%
                        % After pressing a button in the command window,
                        % clear the serial buffer, sample as many times as
                        % specified in the app, then save to touch data
    
                        logData(app);
    
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                        %Iterate point log gauge - Used to specify which
                        %touch is being logged in logData()
                
                        i = i+1;
                        app.PointLogsGauge.Value = i;
                    end

                end

                app.collect = false;
                app.StatusConsoleLabel.Text = "Competed Data Collection";
                app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
                app.ConfirmValidationButton.Enable = "on";
                % app.StartValidationButton.Text = "Reset Collection";
            end

        end

        % Button pushed function: ResetValidationButton
        function ResetValidationButtonPushed(app, event)
            app.validateData.prompts = [];
            app.validateData.readings = [];
            app.validateData.numCalibrations = 0;

            app.collect = false;
            app.PointLogsGauge.Value = 1;

            % also clear plot
            stop(app.plotTimer);
            cla(app.UIAxes);
            app.SelectMLModelDropDown.Value = app.SelectMLModelDropDown.Items(1);
            app.plottedName = {};
            app.plottedData = {};

        end

        % Button pushed function: ConfirmValidationButton
        function ConfirmValidationButtonPushed(app, event)
            % Break loop
            app.collect = false;
               
            % Collect/write data from home dir
            cd(app.homeDir);

            %%%%%% Save Location %%%%%%%%
            fileName = app.SaveNameEditField.Value + "_Validate_" + length(app.validateData.numCalibrations) + "_PL"; 
            fileType = ".mat";
            count = 1;
            pathName = fileName + count + fileType;

            while exist(app.SaveLocationEditField.Value + pathName, "file")
                count = count + 1;
                pathName = fileName+count+fileType;
            end
            app.StatusConsoleLabel.Text = app.SaveLocationEditField.Value + pathName;
            try
                validateData = app.validateData;
                save(app.SaveLocationEditField.Value + pathName, 'validateData');
                app.StatusConsoleLabel.Text = "Save Complete! -> " + app.StatusConsoleLabel.Text;
                app.StatusConsoleLabel.FontColor = [0.47,0.67,0.19];
            catch
                app.StatusConsoleLabel.Text = "Save Failed! -> " + app.StatusConsoleLabel.Text;
                app.StatusConsoleLabel.FontColor = [1,0,0];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Stop updating the graph
            stop(timerfindall)
            delete(timerfindall)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ValidateModelLabel = uilabel(app.UIFigure);
            app.ValidateModelLabel.Text = 'Validate Model';
            app.SelectMLModelDropDownLabel = uilabel(app.UIFigure);
            app.SelectMLModelDropDownLabel.Text = 'Select ML Model';
            app.SelectMLModelDropDown = uidropdown(app.UIFigure);
            app.SelectMLModelDropDown.Items = {'-'};
            app.SelectMLModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectMLModelDropDownOpening, true);
            app.SelectMLModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMLModelDropDownValueChanged, true);
            app.SelectMLModelDropDown.Value = '-';
            app.DescriptionLabel = uilabel(app.UIFigure);
            app.DescriptionLabel.Text = 'Description';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.CalibrateSkinButton = uibutton(app.UIFigure, 'push');
            app.CalibrateSkinButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateSkinButtonPushed, true);
            app.CalibrateSkinButton.Text = 'Calibrate Skin';
            app.TouchDetectionThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.TouchDetectionThresholdEditFieldLabel.Text = 'Touch Detection Threshold';
            app.TouchDetectionThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.TouchDetectionThresholdEditField.Value = 50;
            app.SkinTouchedLampLabel = uilabel(app.UIFigure);
            app.SkinTouchedLampLabel.Text = 'Skin Touched';
            app.SkinTouchedLamp = uilamp(app.UIFigure);
            app.StartValidationButton = uibutton(app.UIFigure, 'push');
            app.StartValidationButton.ButtonPushedFcn = createCallbackFcn(app, @StartValidationButtonPushed, true);
            app.StartValidationButton.Text = 'Start Validation';
            app.PointLogCountEditFieldLabel = uilabel(app.UIFigure);
            app.PointLogCountEditFieldLabel.Text = 'Point Log Count';
            app.PointLogCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointLogCountEditField.ValueChangedFcn = createCallbackFcn(app, @PointLogCountEditFieldValueChanged, true);
            app.PointLogCountEditField.Value = 20;
            app.PointLogsGaugeLabel = uilabel(app.UIFigure);
            app.PointLogsGaugeLabel.Text = 'Point Logs';
            app.PointLogsGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.PointLogsGauge.Limits = [0 50];
            app.PointLogsGauge.MajorTicks = [0 25 50];
            app.PointLogsGauge.Value = 1;
            app.ConfirmValidationButton = uibutton(app.UIFigure, 'push');
            app.ConfirmValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmValidationButtonPushed, true);
            app.ConfirmValidationButton.Enable = 'off';
            app.ConfirmValidationButton.Text = 'Confirm Validation';
            app.ResetValidationButton = uibutton(app.UIFigure, 'push');
            app.ResetValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValidationButtonPushed, true);
            app.ResetValidationButton.Text = 'Reset Validation';
            app.StatusConsoleLabel = uilabel(app.UIFigure);
            app.StatusConsoleLabel.Text = 'StatusConsole';
            app.SaveNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveNameEditField.Value = 'ConeValidation';
            app.SaveNameEditFieldLabel = uilabel(app.UIFigure);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SaveLocationEditField.Value = 'Validation_Data/';
            app.AverageSNRLabel = uilabel(app.UIFigure);
            app.AverageSNRLabel.Text = 'Average SNR:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Validate_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end